classdef Data_Entries < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                     matlab.ui.Figure
        InputsbyNursePanel           matlab.ui.container.Panel
        MEditFieldLabel              matlab.ui.control.Label
        MEditField                   matlab.ui.control.NumericEditField
        MiEditFieldLabel             matlab.ui.control.Label
        MiEditField                  matlab.ui.control.NumericEditField
        TEditFieldLabel              matlab.ui.control.Label
        TEditField                   matlab.ui.control.NumericEditField
        TiEditFieldLabel             matlab.ui.control.Label
        TiEditField                  matlab.ui.control.NumericEditField
        VEditFieldLabel              matlab.ui.control.Label
        VEditField                   matlab.ui.control.NumericEditField
        BEditFieldLabel              matlab.ui.control.Label
        BEditField                   matlab.ui.control.NumericEditField
        ProceedButton                matlab.ui.control.Button
        OutputsbyMedicalDoctorPanel  matlab.ui.container.Panel
        MEEditFieldLabel             matlab.ui.control.Label
        MEEditField                  matlab.ui.control.NumericEditField
        ESTIMATESEditField           matlab.ui.control.EditField
        EditField                    matlab.ui.control.EditField
        MiEEditFieldLabel            matlab.ui.control.Label
        MiEEditField                 matlab.ui.control.NumericEditField
        TEEditFieldLabel             matlab.ui.control.Label
        TEEditField                  matlab.ui.control.NumericEditField
        TiEEditFieldLabel            matlab.ui.control.Label
        TiEEditField                 matlab.ui.control.NumericEditField
        VEEditFieldLabel             matlab.ui.control.Label
        VEEditField                  matlab.ui.control.NumericEditField
        BEEditFieldLabel             matlab.ui.control.Label
        BEEditField                  matlab.ui.control.NumericEditField
        EditField_2                  matlab.ui.control.EditField
        EditField_3                  matlab.ui.control.EditField
        EditField_4                  matlab.ui.control.EditField
        EditField_5                  matlab.ui.control.EditField
        EditField_6                  matlab.ui.control.EditField
        EditField_7                  matlab.ui.control.EditField
    end

    methods (Access = private)

        % Button pushed function: ProceedButton
        function ProceedButtonPushed(app, event)
                        
            global M0 Mi0 T0 Ti0 V0 B0
            M0=app.MEditField.Value;
            Mi0=app.MiEditField .Value;
            T0=app.TEditField.Value;
            Ti0=app.TiEditField.Value;
            V0=app.VEditField.Value;
            B0=app.BEditField.Value;
            Parameters
            solution(M0,Mi0,T0,Ti0,V0,B0)
            global SolTest
            EM=abs(mean(SolTest(:,1)));
            EMi=abs(mean(SolTest(:,2)));
            ET=abs(mean(SolTest(:,3)));
            ETi=abs(mean(SolTest(:,4)));
            EV=abs(mean(SolTest(:,5)));
            EB=abs(mean(SolTest(:,6)));
            
            
            app.MEEditField.Value=EM;
            app.MiEEditField.Value=EMi;
            app.TEEditField.Value=ET;
            app.TiEEditField.Value=ETi;
            app.VEEditField.Value=EV;
            app.BEEditField.Value=EB;
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.InputsbyNursePanel = uipanel(app.UIFigure);
            app.InputsbyNursePanel.Title = 'Inputs by Nurse';
            app.MEditFieldLabel = uilabel(app.InputsbyNursePanel);
            app.MEditFieldLabel.Text = 'M';
            app.MEditField = uieditfield(app.InputsbyNursePanel, 'numeric');
            app.MiEditFieldLabel = uilabel(app.InputsbyNursePanel);
            app.MiEditFieldLabel.Text = 'Mi';
            app.MiEditField = uieditfield(app.InputsbyNursePanel, 'numeric');
            app.TEditFieldLabel = uilabel(app.InputsbyNursePanel);
            app.TEditFieldLabel.Text = 'T';
            app.TEditField = uieditfield(app.InputsbyNursePanel, 'numeric');
            app.TiEditFieldLabel = uilabel(app.InputsbyNursePanel);
            app.TiEditFieldLabel.Text = 'Ti';
            app.TiEditField = uieditfield(app.InputsbyNursePanel, 'numeric');
            app.VEditFieldLabel = uilabel(app.InputsbyNursePanel);
            app.VEditFieldLabel.Text = 'V';
            app.VEditField = uieditfield(app.InputsbyNursePanel, 'numeric');
            app.BEditFieldLabel = uilabel(app.InputsbyNursePanel);
            app.BEditFieldLabel.Text = 'B';
            app.BEditField = uieditfield(app.InputsbyNursePanel, 'numeric');
            app.ProceedButton = uibutton(app.InputsbyNursePanel, 'push');
            app.ProceedButton.ButtonPushedFcn = createCallbackFcn(app, @ProceedButtonPushed, true);
            app.ProceedButton.Text = 'Proceed';
            app.OutputsbyMedicalDoctorPanel = uipanel(app.UIFigure);
            app.OutputsbyMedicalDoctorPanel.Title = 'Outputs by Medical Doctor';
            app.MEEditFieldLabel = uilabel(app.OutputsbyMedicalDoctorPanel);
            app.MEEditFieldLabel.Text = 'ME';
            app.MEEditField = uieditfield(app.OutputsbyMedicalDoctorPanel, 'numeric');
            app.ESTIMATESEditField = uieditfield(app.OutputsbyMedicalDoctorPanel, 'text');
            app.ESTIMATESEditField.Value = 'ESTIMATES';
            app.EditField = uieditfield(app.OutputsbyMedicalDoctorPanel, 'text');
            app.EditField.Value = 'HEALTHY REFERENCE ([Min, Max])';
            app.MiEEditFieldLabel = uilabel(app.OutputsbyMedicalDoctorPanel);
            app.MiEEditFieldLabel.Text = 'MiE';
            app.MiEEditField = uieditfield(app.OutputsbyMedicalDoctorPanel, 'numeric');
            app.TEEditFieldLabel = uilabel(app.OutputsbyMedicalDoctorPanel);
            app.TEEditFieldLabel.Text = 'TE';
            app.TEEditField = uieditfield(app.OutputsbyMedicalDoctorPanel, 'numeric');
            app.TiEEditFieldLabel = uilabel(app.OutputsbyMedicalDoctorPanel);
            app.TiEEditFieldLabel.Text = 'TiE';
            app.TiEEditField = uieditfield(app.OutputsbyMedicalDoctorPanel, 'numeric');
            app.VEEditFieldLabel = uilabel(app.OutputsbyMedicalDoctorPanel);
            app.VEEditFieldLabel.Text = 'VE';
            app.VEEditField = uieditfield(app.OutputsbyMedicalDoctorPanel, 'numeric');
            app.BEEditFieldLabel = uilabel(app.OutputsbyMedicalDoctorPanel);
            app.BEEditFieldLabel.Text = 'BE';
            app.BEEditField = uieditfield(app.OutputsbyMedicalDoctorPanel, 'numeric');
            app.EditField_2 = uieditfield(app.OutputsbyMedicalDoctorPanel, 'text');
            app.EditField_2.Value = '[500,1500]/mm3';
            app.EditField_3 = uieditfield(app.OutputsbyMedicalDoctorPanel, 'text');
            app.EditField_3.Value = '     [0,500]/mm3';
            app.EditField_4 = uieditfield(app.OutputsbyMedicalDoctorPanel, 'text');
            app.EditField_4.Value = ' [500,1200]/mm3';
            app.EditField_5 = uieditfield(app.OutputsbyMedicalDoctorPanel, 'text');
            app.EditField_5.Value = '   [0,500]/mm3';
            app.EditField_6 = uieditfield(app.OutputsbyMedicalDoctorPanel, 'text');
            app.EditField_6.Value = '  [0,1000] Copies';
            app.EditField_7 = uieditfield(app.OutputsbyMedicalDoctorPanel, 'text');
            app.EditField_7.Value = '     [0,100] copies';
        end
    end

    methods (Access = public)

        % Construct app
        function app = Data_Entries

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end